classdef Cheia_Limnimetrica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        LegendTextArea         matlab.ui.control.TextArea
        LegendTextAreaLabel    matlab.ui.control.Label
        bEditFieldLabel_6      matlab.ui.control.Label
        NEditField_6           matlab.ui.control.NumericEditField
        bEditFieldLabel_5      matlab.ui.control.Label
        NEditField_5           matlab.ui.control.NumericEditField
        bEditFieldLabel_4      matlab.ui.control.Label
        bEditFieldLabel_3      matlab.ui.control.Label
        bEditFieldLabel_2      matlab.ui.control.Label
        CheialimnimetricLabel  matlab.ui.control.Label
        NEditField_4           matlab.ui.control.NumericEditField
        NEditField_3           matlab.ui.control.NumericEditField
        NEditField_2           matlab.ui.control.NumericEditField
        UITable2               matlab.ui.control.Table
        STARTButton            matlab.ui.control.Button
        UITable                matlab.ui.control.Table
        bEditField             matlab.ui.control.NumericEditField
        bEditFieldLabel        matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
           cla(app.UIAxes);
    app.UITable.Data = [];
    app.UITable2.Data = [];
    b = app.bEditField.Value;
    m = app.NEditField_2.Value;
    I = app.NEditField_3.Value/1000;
    n1 = app.NEditField_4.Value;
    n2 = app.NEditField_5.Value;
    Qi = app.NEditField_6.Value;

    i = 1; A = []; P = []; R = []; C1 = []; v1 = []; Q1 = []; h11 = [];
    for h1 = 0:0.5:50
        A(i)   = (b + m * h1) * h1;
        P(i)   = b + 2 * h1 * sqrt(1 + m^2);
        R(i)   = A(i) / P(i);
        C1(i)  = (1 / n1) * R(i)^(1/6);
        v1(i)  = C1(i) * sqrt(R(i) * I);
        Q1(i)  = v1(i) * A(i);
        h11(i) = h1;
        if Q1(i) > Qi
            break
        end
        i = i + 1;
    end

   
    i = 1; A2 = []; P2 = []; R2 = []; C2 = []; v2 = []; Q2 = []; h22 = [];
    for h2 = 0:0.5:50
        A2(i)  = (b + m * h2) * h2;
        P2(i)  = b + 2 * h2 * sqrt(1 + m^2);
        R2(i)  = A2(i) / P2(i);
        C2(i)  = (1 / n2) * R2(i)^(1/6);
        v2(i)  = C2(i) * sqrt(R2(i) * I);
        Q2(i)  = v2(i) * A2(i);
        h22(i) = h2;
        if Q2(i) > Qi
            break
        end
        i = i + 1;
    end

    
    plot(app.UIAxes, Q1, h11, '-o', Q2, h22, '-*')
    
    
    deg1 = min(2, length(Q1) - 1);
    deg2 = min(3, length(Q2) - 1);
    Pol1 = polyfit(Q1, h11, deg1);
    Pol2 = polyfit(Q2, h22, deg2);
    h1 = interp1(Q1, h11, Qi, 'spline');
    h2 = interp1(Q2, h22, Qi, 'spline');
    
    maxLen = max(length(A), length(A2));

  
    A_c   = [num2cell(A),   repmat({'null'}, 1, maxLen - length(A))];
    P_c   = [num2cell(P),   repmat({'null'}, 1, maxLen - length(P))];
    R_c   = [num2cell(R),   repmat({'null'}, 1, maxLen - length(R))];
    C1_c  = [num2cell(C1),  repmat({'null'}, 1, maxLen - length(C1))];
    v1_c  = [num2cell(v1),  repmat({'null'}, 1, maxLen - length(v1))];
    Q1_c  = [num2cell(Q1),  repmat({'null'}, 1, maxLen - length(Q1))];
    h11_c = [num2cell(h11), repmat({'null'}, 1, maxLen - length(h11))];

    A2_c  = [num2cell(A2),  repmat({'null'}, 1, maxLen - length(A2))];
    P2_c  = [num2cell(P2),  repmat({'null'}, 1, maxLen - length(P2))];
    R2_c  = [num2cell(R2),  repmat({'null'}, 1, maxLen - length(R2))];
    C2_c  = [num2cell(C2),  repmat({'null'}, 1, maxLen - length(C2))];
    v2_c  = [num2cell(v2),  repmat({'null'}, 1, maxLen - length(v2))];
    Q2_c  = [num2cell(Q2),  repmat({'null'}, 1, maxLen - length(Q2))];
    h22_c = [num2cell(h22), repmat({'null'}, 1, maxLen - length(h22))];

   %Tabel
    T = table(h11_c', A_c', P_c', R_c', C1_c', v1_c', Q1_c', ...
              C2_c', v2_c', Q2_c', ...
              'VariableNames', {'h [m]', 'A [m^2]', 'P [m]', ...
              'R [m]', 'C_1 [m^(0,5)/s]', 'v_1 [m/s]', ...
              'Q_1 [m^3/s]', 'C_2 [m^(0,5)/s]', 'v_2 [m/s]', ...
              'Q_2 [m^3/s]'});
    app.UITable.Data = T;


    hold(app.UIAxes, 'on');
    plot(app.UIAxes, Qi, h1, 'ok', Qi, h2, '*k', 'LineWidth', 1.5);
    legend(app.UIAxes, 'Rugozitate n_1', 'Rugozitate n_2', 'h_1 la Q instalat', 'h_2 la Q instalat');
    hold(app.UIAxes, 'off');

    TT = table(h1, h2, 'VariableNames', {'h1_pol [m]', 'h2_pol [m]'});
    app.UITable2.Data = TT;

%%%%%%%%


   % salvare grafic ca imagine
imgFile = fullfile(pwd, 'Cheia_Limnimetrica.png');
exportgraphics(app.UIAxes, imgFile, 'Resolution', 300);

% excel
filename = fullfile(pwd, 'Cheia_Limnimetrica.xlsx');
if exist(filename, 'file')
    delete(filename);
end

% export table
% UITable (tabelul mare) începe la A1
writetable(app.UITable.Data, filename, 'Sheet', 'Date_Cheie', 'Range', 'A1');

% UITable2 (rezultatele cu h1 și h2) începe la L1, în dreapta
writetable(app.UITable2.Data, filename, 'Sheet', 'Date_Cheie', 'Range', 'L1');

% inserare grafice în microsoft excel
try
    excelApp = actxserver('Excel.Application');
    excelApp.Visible = true; % deschide fisierul dupa creare si completare automat
    
    wb = excelApp.Workbooks.Open(filename);
    sheet = wb.Sheets.Item('Date_Cheie');
    
   
    sheet.Shapes.AddPicture(imgFile, 0, 1, 550, 100, 500, 350); 
    
    wb.Save;
catch
    disp('Fișierul Excel a fost salvat. Graficul este disponibil și ca imagine .png.');
end
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
            
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            value = app.bEditField.Value;
            
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            
        end

        % Display data changed function: UITable2
        function UITable2DisplayDataChanged(app, event)
            newDisplayData = app.UITable2.DisplayData;
            
        end

        % Value changed function: NEditField_2
        function NEditField_2ValueChanged(app, event)
            value = app.NEditField_2.Value;
            
        end

        % Value changed function: NEditField_3
        function NEditField_3ValueChanged(app, event)
            value = app.NEditField_3.Value;
            
        end

        % Value changed function: NEditField_4
        function NEditField_4ValueChanged(app, event)
            value = app.NEditField_4.Value;
            
        end

        % Value changed function: NEditField_5
        function NEditField_5ValueChanged(app, event)
            value = app.NEditField_5.Value;
            
        end

        % Value changed function: NEditField_6
        function NEditField_6ValueChanged(app, event)
            value = app.NEditField_6.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cheia limnimetrică')
            xlabel(app.UIAxes, 'Debit [mc/s]')
            ylabel(app.UIAxes, 'Adâncime [m]')
            zlabel(app.UIAxes, 'Z')
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b = ';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'h [m]'; 'A [mp]'; 'P [m]'; 'R [m]'; 'C1 [m^0.5/s]'; 'v1 [m/s]'; 'Q1 [mc/s]'; 'C2 [m^0.5/s]'; 'v2 [m/s]'; 'Q2 [mc/s]'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = {'START + Export Excel'; 'Așteaptă câteva secunde'};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'h1'; 'h2'};
            app.UITable2.RowName = {};
            app.UITable2.DisplayDataChangedFcn = createCallbackFcn(app, @UITable2DisplayDataChanged, true);
            app.NEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.NEditField_2.ValueChangedFcn = createCallbackFcn(app, @NEditField_2ValueChanged, true);
            app.NEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.NEditField_3.ValueChangedFcn = createCallbackFcn(app, @NEditField_3ValueChanged, true);
            app.NEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.NEditField_4.ValueChangedFcn = createCallbackFcn(app, @NEditField_4ValueChanged, true);
            app.CheialimnimetricLabel = uilabel(app.UIFigure);
            app.CheialimnimetricLabel.Text = 'Cheia limnimetrică pentru canal trapezoidal';
            app.bEditFieldLabel_2 = uilabel(app.UIFigure);
            app.bEditFieldLabel_2.Text = 'm = ';
            app.bEditFieldLabel_3 = uilabel(app.UIFigure);
            app.bEditFieldLabel_3.Text = 'I = ';
            app.bEditFieldLabel_4 = uilabel(app.UIFigure);
            app.bEditFieldLabel_4.Text = 'n1 = ';
            app.NEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.NEditField_5.ValueChangedFcn = createCallbackFcn(app, @NEditField_5ValueChanged, true);
            app.bEditFieldLabel_5 = uilabel(app.UIFigure);
            app.bEditFieldLabel_5.Text = 'n2 = ';
            app.NEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.NEditField_6.ValueChangedFcn = createCallbackFcn(app, @NEditField_6ValueChanged, true);
            app.bEditFieldLabel_6 = uilabel(app.UIFigure);
            app.bEditFieldLabel_6.Text = 'Qi = ';
            app.LegendTextAreaLabel = uilabel(app.UIFigure);
            app.LegendTextAreaLabel.Text = 'Legendă';
            app.LegendTextArea = uitextarea(app.UIFigure);
            app.LegendTextArea.Value = {'b = baza mică [m]'; 'm = panta taluzelor '; 'I = panta canalului [‰]'; 'n1 = rugozitate 1'; 'n2 = rugozitate 2'; 'Qi = debit instalat [mc/s]'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cheia_Limnimetrica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end